classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image                       matlab.ui.control.Image
        UploadButton                matlab.ui.control.Button
        ObjectDetectionButton       matlab.ui.control.Button
        ImageSegmentationButton     matlab.ui.control.Button
        CharacterRecognitionButton  matlab.ui.control.Button
        Label                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        im; % Description
        imbin;
        Iprops;
        count;
        noPlate;
        h;
        w; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [file, path] = uigetfile('*.*');
            app.im = imread(file);
            app.Image.ImageSource = file;
        end

        % Button pushed function: ObjectDetectionButton
        function ObjectDetectionButtonPushed(app, event)
            imgray = rgb2gray(app.im);
            app.imbin = imbinarize(imgray);
            app.im = edge(imgray, 'prewitt');
            figure, imshow(app.im), title('Object Detection');
            app.Iprops=regionprops(app.im,'BoundingBox','Area', 'Image');
            app.count = numel(app.Iprops);
            for i = 1:app.count
                rectangle('Position', app.Iprops(i).BoundingBox, 'EdgeColor', 'r', 'LineWidth', 2);
            end
        end

        % Button pushed function: ImageSegmentationButton
        function ImageSegmentationButtonPushed(app, event)
            maxa = app.Iprops.Area;
            boundingBox = app.Iprops.BoundingBox;
            for i=1:app.count
                if maxa<app.Iprops(i).Area
                    maxa=app.Iprops(i).Area;
                    boundingBox=app.Iprops(i).BoundingBox;
                end
            end  
            app.im = imcrop(app.imbin, boundingBox);
            app.im = bwareaopen(~app.im, 500);
            [app.h, app.w] = size(app.im);%get width
            figure, imshow(app.im), title('Character Detection');
        end

        % Button pushed function: CharacterRecognitionButton
        function CharacterRecognitionButtonPushed(app, event)
            app.Iprops=regionprops(app.im,'BoundingBox','Area', 'Image'); %read letter
            app.count = numel(app.Iprops);
            for i = 1:app.count
                rectangle('Position', app.Iprops(i).BoundingBox, 'EdgeColor', 'r', 'LineWidth', 2);
            end
            app.noPlate=[];
            for i=1:app.count
                ow = length(app.Iprops(i).Image(1,:));
                oh = length(app.Iprops(i).Image(:,1));
                if ow<(app.h/2) & oh>(app.h/3)
                    letter=Letter_detection(app.Iprops(i).Image);
                    app.noPlate=[app.noPlate letter];
                end
            end
            app.Label.Text = app.noPlate;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.ObjectDetectionButton = uibutton(app.UIFigure, 'push');
            app.ObjectDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectDetectionButtonPushed, true);
            app.ObjectDetectionButton.Text = 'Object Detection';
            app.ImageSegmentationButton = uibutton(app.UIFigure, 'push');
            app.ImageSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @ImageSegmentationButtonPushed, true);
            app.ImageSegmentationButton.Text = 'Image Segmentation';
            app.CharacterRecognitionButton = uibutton(app.UIFigure, 'push');
            app.CharacterRecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @CharacterRecognitionButtonPushed, true);
            app.CharacterRecognitionButton.Text = 'Character Recognition';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end